classdef cull_images_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        file_menu               matlab.ui.container.Menu
        images_menu             matlab.ui.container.Menu
        raw_menu                matlab.ui.container.Menu
        dmb_menu                matlab.ui.container.Menu
        modalities_menu         matlab.ui.container.Menu
        mod_edit_menu           matlab.ui.container.Menu
        help_menu               matlab.ui.container.Menu
        shortcuts_menu          matlab.ui.container.Menu
        instructions_menu       matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        img_space               matlab.ui.control.UIAxes
        mini_montage            matlab.ui.control.UIAxes
        area_hist               matlab.ui.control.UIAxes
        paths_title             matlab.ui.control.Label
        mod_title               matlab.ui.control.Label
        modality_listbox        matlab.ui.control.ListBox
        prog_title              matlab.ui.control.Label
        missing_list_title      matlab.ui.control.Label
        missing_list            matlab.ui.control.ListBox
        batch_title             matlab.ui.control.Label
        mid_wid_label           matlab.ui.control.Label
        min_ht_label            matlab.ui.control.Label
        min_frame_label         matlab.ui.control.Label
        min_wd_txt              matlab.ui.control.EditField
        min_ht_txt              matlab.ui.control.EditField
        min_nframes_txt         matlab.ui.control.EditField
        batch_go_btn            matlab.ui.control.Button
        img_data_title          matlab.ui.control.Label
        current_wd              matlab.ui.control.Label
        current_ht              matlab.ui.control.Label
        current_nframes         matlab.ui.control.Label
        current_cropped         matlab.ui.control.Label
        keep_btn                matlab.ui.control.Button
        reject_btn              matlab.ui.control.Button
        finish_btn              matlab.ui.control.Button
        start_btn               matlab.ui.control.Button
        next_btn                matlab.ui.control.Button
        prev_btn                matlab.ui.control.Button
        prog_list               matlab.ui.control.ListBox
        ht_slider               matlab.ui.control.Slider
        current_img_tbl         matlab.ui.control.Table
        dmb_led                 matlab.ui.control.Lamp
        raw_led                 matlab.ui.control.Lamp
        MinimontageSwitchLabel  matlab.ui.control.Label
        align_switch            matlab.ui.control.Switch
        raw_path_label          matlab.ui.control.Label
        dmb_path_label          matlab.ui.control.Label
    end

    
    properties (Access = public)
        img_path = ''; % Path to images
        raw_path = ''; % Path to videos
        dmb_path = ''; % Path to dmbs
        out_path = ''; % Path to output
        
        % Image data
        imgs;
        img_header; % column labels for image data
        
        % Indices
        current_vid = -1; % Current video under review
        current_indices; % Indices of the imgs array of the current image option
        current_option_idx = 1; % Current row of current_img_tbl
        
        fnames = {}; % current file names under consideration
        loadedImgs; % buffered images for review
        avi_nums; % list of video numbers
        ht_roi; % rectangular roi that indicates the heights to be rejected
        max_ht; % Maximum height of all images
    end
    
    properties (Access = private)
        off_color = [0, 0.2, 0] % color when led's are off
        on_color = [0, 1, 0]; % color when led's are on
        started = false; % Whether individual decision making has begun
    end
    
    methods (Access = private)
%         function dmb_path_txt_CreateFcn(app, hObject, eventdata)
%             % hObject    handle to dmb_path_txt (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % app    empty - app not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
%         
%         function images_path_txt_CreateFcn(app, hObject, eventdata)
%             % hObject    handle to images_path_txt (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % app    empty - app not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
%         
%         function min_cropped_txt_CreateFcn(app, hObject, eventdata)
%             % hObject    handle to min_cropped_txt (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % app    empty - app not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
%         
%         function other_imgs_path_txt_CreateFcn(app, hObject, eventdata)
%             % hObject    handle to other_imgs_path_txt (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % app    empty - app not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
%         
%         function out_menu_Callback(app, hObject, eventdata)
%             % hObject    handle to out_menu (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % app    structure with app and user data (see GUIDATA)
%             def_path = '.';
%             if isfield(app, 'img_path')
%                 def_path = app.img_path;
%             end
%             out_path = uigetdir(def_path, 'Set output path');
%             if isnumeric(out_path)
%                 return;
%             end
%             
%             % Update checkbox
%             set(app.out_cb, 'value', true);
%             
%             % Update app with raw_path
%             app.out_path = out_path;
%             guidata(hObject, app);
%         end
%         
%         function output_path_txt_CreateFcn(app, hObject, eventdata)
%             % hObject    handle to output_path_txt (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % app    empty - app not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
%         
%         function raw_path_txt_CreateFcn(app, hObject, eventdata)
%             % hObject    handle to raw_path_txt (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % app    empty - app not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end


        function updateBatchRejectSlider(app, max_ht, ht_pos)
            if exist('max_ht', 'var') && ~isempty(max_ht)
                app.ht_slider.Limits = [0, max_ht];
                app.ht_slider.MajorTicks = linspace(0, max_ht, 5);
                app.ht_slider.MajorTickLabels = cellfun(@(x) sprintf('%0.1f', x), mat2cell(linspace(0, max_ht, 5)', ones(5,1)), 'UniformOutput', false);
                app.ht_slider.MinorTicks = [];
            end
            if exist('ht_pos', 'var') && ~isempty(ht_pos)
                app.ht_slider.Value = ht_pos;
                updateHtROI(app, ht_pos)
            end
        end
        
        function updateHtROI(app, new_value)
            % Update ROI
            roi_orig_pos = get(app.ht_roi, 'position');
            roi_new_pos = roi_orig_pos;
            roi_new_pos(3) = 1+new_value;
            set(app.ht_roi, 'position', roi_new_pos);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function cull_images_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % app    structure with app and user data (see GUIDATA)
%             % varargin   command line arguments to cull_images (see VARARGIN)
%             
%             % Choose default command line output for cull_images
%             app.output = hObject;
%             
%             % Update app structure
%             guidata(hObject, app);
%             
            addpath(fullfile('.','lib'));
            
            % Set colors of led's
            set(app.dmb_led, 'color', app.off_color)
            set(app.raw_led, 'color', app.off_color)
            
            % Image space should be blank
            imshow(0, 'parent', app.img_space)
            
            % Disable default interactivity for graphics
            app.img_space.Toolbar.Visible = 'off';
            app.area_hist.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.img_space)
            disableDefaultInteractivity(app.area_hist)
        end

        % Menu selected function: images_menu
        function images_menu_Callback(app, event)
            % hObject    handle to images_menu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            exts = {'*.tif', 'tif'; '*.dat', 'dat'};
            def_path = '.';
            if isfield(app, 'img_path')
                def_path = app.img_path;
            end
            [img_fnames, app.img_path] = uigetfile(exts, 'Load images', def_path, 'multiselect', 'on');
            if isnumeric(img_fnames)
                return;
            elseif ~iscell(img_fnames)
                img_fnames = {img_fnames};
            end
            img_fnames = img_fnames';
            
            tmp_imgs = [img_fnames, cellstr(repmat(app.img_path, size(img_fnames, 1), 1))];
            
            %% Process files, extract useful information
            [tmp_imgs, app.img_header] = processFnames(tmp_imgs, app);
            
            % If imgs already exists, then this is an attempt to add new images.
            % (todo): handle the case where "non-unique" images are added. Add only if date modified is later.
            if isfield(app, 'imgs')
                tmp_imgs = [app.imgs; tmp_imgs];
            end
            app.imgs = tmp_imgs;
            
            %% Update height histogram for batch editing
            % (todo) handle situation where more images are added
            [app.max_ht, suggest_thr, app.ht_roi] = updateHtHist(app.area_hist, ...
                cell2mat(tmp_imgs(:, strcmp(app.img_header,'ht'))));
            app.min_ht_txt.Value = sprintf('%i', round(suggest_thr));
            updateBatchRejectSlider(app, app.max_ht, suggest_thr)
            app.ht_slider.Enable = 'on';
            
%             pos = getPosition(app.slider_h);
%             set(app.min_ht_txt, 'string', num2str(round(pos(1))));
%             addNewPositionCallback(app.slider_h, ...
%                 @(pos) set(app.min_ht_txt, 'string', num2str(round(pos(1)))));
            
            % Update other GUI elements
            updateProgress(app);
            set(app.prog_list, 'enable', 'on');
            if isfield(app, 'avi_nums')
                updateMissingImgs(app);
            end
            
            % Once the image are loaded, can start culling
            set(app.min_wd_txt,         'enable', 'on');
            set(app.min_ht_txt,         'enable', 'on');
            set(app.min_nframes_txt,    'enable', 'on');
            set(app.batch_go_btn,       'enable', 'on');
            set(app.start_btn,          'enable', 'on');
            
            % Update app with img_path and imgs
%             guidata(hObject, app);
        end

        % Button pushed function: batch_go_btn
        function batch_go_btn_Callback(app, event)
            % hObject    handle to batch_go_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            min_ht      = str2double(get(app.min_ht_txt, 'Value'));
            min_wd      = str2double(get(app.min_wd_txt, 'Value'));
            min_frames  = str2double(get(app.min_nframes_txt, 'Value'));
            min_cropped = 1; % by default
            
            hts     = cell2mat(app.imgs(:, strcmpi(app.img_header, 'ht')));
            wds     = cell2mat(app.imgs(:, strcmpi(app.img_header, 'wd')));
            nframes = cell2mat(app.imgs(:, strcmpi(app.img_header, 'nframes')));
            cropped = cell2mat(app.imgs(:, strcmpi(app.img_header, 'cropped')));
            
            % First overwrite all decisions with undecided to facilitate multiple batch attempts
            % Don't worry, this button will be disabled when the user hits start, so they won't be able to undo
            % any of their progress during individual culling
            app.imgs(:, strcmp(app.img_header, 'decisions')) = ...
                cellstr(repmat('undecided', size(app.imgs, 1), 1));
            
            % Then figure out which ones are rejected by the new criteria
            rejected = hts < min_ht | wds < min_wd | nframes < min_frames | cropped <= min_cropped;
            app.imgs(rejected, strcmp(app.img_header, 'decisions')) = ...
                cellstr(repmat('rejected', numel(find(rejected)), 1));
            
            % Update other GUI elements
            updateProgress(app);
            % (todo) activate updateHtHist(), but that will require changing its input to varargin
            % updateHtHist()
            if isfield(app, 'avi_nums')
                updateMissingImgs(app);
            end
            
            % Update guidata
%             guidata(hObject, app);
        end

        % Value changed function: ht_slider
        function ht_sliderValueChanged(app, event)
            app.min_ht_txt.Value = sprintf('%i', round(app.ht_slider.Value));
            updateHtROI(app, app.ht_slider.Value);
        end

        % Value changing function: ht_slider
        function ht_sliderValueChanging(app, event)
            app.min_ht_txt.Value = sprintf('%i', round(event.Value));
            updateHtROI(app, app.ht_slider.Value);
        end

        % Menu selected function: dmb_menu
        function dmb_menu_Callback(app, event)
            % hObject    handle to dmb_menu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            def_path = '.';
            if ~isempty(app.raw_path)
                def_path = app.raw_path;
            elseif ~isempty(app.img_path)
                def_path = app.img_path;
            end
            tmp_dmb_path = uigetdir(def_path, 'Set path to .dmb''s');
            if isnumeric(tmp_dmb_path)
                return;
            end
            set(app.dmb_led, 'color', app.on_color);
            
            % Update app with dmb_path
            app.dmb_path = tmp_dmb_path;
%             guidata(hObject, app);
        end

        % Window key press function: figure1
        function figure1_WindowKeyPressFcn(app, event)
            % hObject    handle to figure1 (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % app    structure with app and user data (see GUIDATA)
            if isempty(app.loadedImgs)
                return;
            end
            switch event.Key
                case 'n'
                    next_btn_Callback(app, event);
                case 'p'
                    prev_btn_Callback(app, event);
                case 'uparrow' % see previous option
                    try % skip if the current focus is a listbox
                        if strcmp(get(gco, 'style'), 'listbox')
                            return
                        end
                    catch
                    end
                    orig_idx = app.current_option_idx;
%                     orig = app.current_img_tbl.Data{orig_idx};
                    
                    found = false;
                    while app.current_option_idx > 1 && ~found
                        app.current_option_idx = app.current_option_idx -1;
                        % skip rejected
                        current_i = strcmp(...
                            app.imgs(:, strcmp(app.img_header, 'fname')), ...
                            app.current_img_tbl.Data{app.current_option_idx});
                        if ~strcmp(app.imgs{current_i, strcmp(app.img_header, 'decisions')}, 'rejected')
                            found = true;
                            break;
                        end
                    end
                    if ~found
                        app.current_option_idx = orig_idx;
                        return
                    end
                    % Update gui with new image option
%                     current_img_listbox_Callback(hObject, event, app)
                    e.Indices = app.current_option_idx;
                    current_img_tblCellSelection(app, e);
            
                case 'downarrow' % see next option
                    try % skip if the current focus is a listbox
                        if strcmp(get(gco, 'style'), 'listbox')
                            return
                        end
                    catch
                    end
                    orig_idx = app.current_option_idx;
%                     orig = app.current_img_tbl.Data{orig_idx};
                    
%                     orig = get(app.current_img_listbox, 'value');
                    found = false;
                    while app.current_option_idx < numel(app.current_img_tbl.Data) && ~found
                        app.current_option_idx = app.current_option_idx +1;
%                         set(app.current_img_listbox, 'value', get(app.current_img_listbox, 'value')+1);
                        % skip rejected
                        current_i = strcmp(...
                            app.imgs(:, strcmp(app.img_header, 'fname')), ...
                            app.current_img_tbl.Data{app.current_option_idx});
                        if ~strcmp(app.imgs{current_i, strcmp(app.img_header, 'decisions')}, 'rejected')
                            found = true;
                        end
                    end
                    if ~found
                        app.current_option_idx = orig_idx;
                        return
                    end
                    % Update gui with new image option
%                     current_img_listbox_Callback(hObject, eventdata, app)
                    e.Indices = app.current_option_idx;
                    current_img_tblCellSelection(app, e)
            
                case 'rightarrow' % shortcut for keep
                    if ~isempty(event.Modifier)
                        return;
                    end
                    keep_btn_Callback(app, event)
            
                case 'leftarrow' % % shortcut for reject
                    if ~isempty(event.Modifier)
                        return;
                    end
                    reject_btn_Callback(app, event)
            end
        end

        % Button pushed function: finish_btn
        function finish_btn_Callback(app, event)
            % hObject    handle to finish_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Check if numel kept == 0
            if ~any(strcmp(app.imgs(:, strcmp(app.img_header, 'decisions')), 'kept'))
                return;
            end
            % Get output path
            app.out_path = uigetdir(app.img_path, 'Select output directory');
            if isnumeric(app.out_path)
                return;
            end
            
            kept_imgs = app.imgs(...
                strcmp(app.imgs(:, strcmp(app.img_header, 'decisions')), 'kept'), :);
            
            % Create waitbar
            wb = waitbar(0, sprintf('Extra space for long file names Copying %s...', kept_imgs{1,1}));
            wb.Children.Title.Interpreter = 'none';
            waitbar(0, wb, sprintf('Copying %s...', kept_imgs{1}));
            
            for ii=1:size(kept_imgs, 1)
                waitbar(ii/size(kept_imgs, 1), wb, ...
                    sprintf('Copying %s...', kept_imgs{ii, strcmp(app.img_header, 'fname')}));
            
                % If kept image is a .dat, should read and write to out_path
                [tmp_img_path, img_name, img_ext] = fileparts(...
                    fullfile(kept_imgs{ii, strcmp(app.img_header, 'path')}, ...
                    kept_imgs{ii, strcmp(app.img_header, 'fname')}));
            
                if strcmp(img_ext, '.dat')
                    imwrite(readDatImg(fullfile(tmp_img_path, [img_name, img_ext])), ...
                        fullfile(app.out_path, [img_name, '_dat.tif']));
                else
                    copyfile(fullfile(tmp_img_path, [img_name, img_ext]), ...
                        fullfile(app.out_path, [img_name, img_ext]));
                end
            end
            
            % Output .dmb's too
            if ~isempty(app.dmb_path)
                dmbs = getDMBs(app, kept_imgs);
                out_dmb_path = fullfile(app.out_path, 'kept_dmb_files');
                mkdir(out_dmb_path);
            
                for ii=1:size(dmbs, 1)
                    [~, dmb_name, dmb_ext] = fileparts(dmbs{ii});
                    copyfile(dmbs{ii}, fullfile(out_dmb_path, [dmb_name, dmb_ext]));
            
                    waitbar(ii/size(dmbs, 1), wb, sprintf('Copying %s...', [dmb_name, dmb_ext]));
                end
            end
            
            
            close(wb);
            close(gcf);
        end

        % Button pushed function: keep_btn
        function keep_btn_Callback(app, event)
            % hObject    handle to keep_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            decide(app, 'kept');
        end

        % Value changed function: min_ht_txt
        function min_ht_txt_Callback(app, event)
            % Update slider position
            new_value = str2double(event.Value);
            try
                if new_value > app.max_ht
                    new_value = app.max_ht;
                elseif new_value < 0
                    new_value = 0;
                end
                app.ht_slider.Value = new_value;
                updateHtROI(app, new_value);
                set(app.min_ht_txt, 'Value', sprintf('%i', round(new_value)))
            catch
                uialert(app.figure1, 'Invalid input', 'error');
            end
        end

        % Menu selected function: mod_edit_menu
        function mod_edit_menu_Callback(app, event)
            % hObject    handle to mod_edit_menu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            extra_spaces    = 3;
            curr_mod_ls     = get(app.modality_listbox, 'string');
            def_ans         = [curr_mod_ls; cellstr(strings(extra_spaces,1))];
            dlg_title       = 'Add or remove modalities to cull';
            n_lines         = [ones(size(def_ans)), ones(size(def_ans)).*(6*length('split_det'))];
            prompt          = cellstr([repmat('modality ',size(def_ans,1),1), ...
                num2str((1:size(def_ans,1))')]);
            
            re = inputdlg(prompt, dlg_title, n_lines, def_ans);
            re(cellfun(@isempty, re)) = []; % remove empty elements
            
            if isempty(re)
                return;
            end
            
            % Update app with new modality list
            set(app.modality_listbox, 'string', re);
            guidata(hObject, app);
        end

        % Button pushed function: next_btn
        function next_btn_Callback(app, event)
            % hObject    handle to next_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %% Always start new image with option 1
            app.current_option_idx = 1;
            
            %% Find video after current video which has undecided images
            nums = unique(cell2mat(app.imgs(:, strcmp(app.img_header, 'num'))));
            if find(nums == app.current_vid) == numel(nums)
                return;
            elseif app.current_vid < 0 % find first video
                srch = 1:numel(nums);
            else
                srch = find(nums == app.current_vid)+1:numel(nums);
            end
            
            reached_end = true;
            for ii=srch
                app.current_indices = cell2mat(app.imgs(:, strcmp(app.img_header, 'num'))) == ...
                    nums(ii);
                if hasUndecided(app, app.current_indices)
                    app.current_vid = nums(ii);
                    reached_end = false;
                    break;
                end
            end
            if reached_end
                return;
            end
            
            %% Update everything
            app.fnames = updateImgOpts(app, app.current_indices, 1);
            app.loadedImgs = loadImgs(app, app.fnames);
            updateImageSpace(app);
            updateImageData(app);
            
            if strcmpi(get(app.align_switch, 'value'), 'on')
                app.wires = getMiniMontage(app);
                app.wire_vid = app.current_vid;
                updateMiniMontage(app);
            end
            
            %% Update guidata
%             guidata(hObject, app);
        end

        % Button pushed function: prev_btn
        function prev_btn_Callback(app, event)
            % hObject    handle to prev_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % todo: make behavior for next and previous the same except for the direction
            
            %% Always start new image with option 1
            app.current_option_idx = 1;
            
            % Find video before current video
            nums = unique(cell2mat(app.imgs(:, strcmp(app.img_header, 'num'))));
            if app.current_vid == nums(1)
                return;
            else
                app.current_vid = nums(find(nums == app.current_vid)-1);
            end
            app.current_indices = cell2mat(app.imgs(:, strcmp(app.img_header, 'num'))) == ...
                app.current_vid;
            
            % Update everything
            app.fnames = updateImgOpts(app, app.current_indices, 1);
            app.loadedImgs = loadImgs(app, app.fnames);
            updateImageSpace(app);
            updateImageData(app);
            
            if strcmpi(app.align_switch.Value, 'on')
                app.wires = getMiniMontage(app);
                app.wire_vid = app.current_vid;
                updateMiniMontage(app);
            end
            
%             guidata(hObject, app);
        end

        % Value changed function: prog_list
        function prog_list_Callback(app, event)
            % hObject    handle to prog_list (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns prog_list contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from prog_list
            
            if isprop(app, 'started') && app.started
                %prog_string = get(hObject, 'string');
                %entry = prog_string{get(hObject, 'value')};
                entry = event.Value;
                num = str2double(entry(1:strfind(entry, ':')-1));
            
                % Set current video to selected video
                app.current_vid = num;
                app.current_indices = cell2mat(app.imgs(:, strcmp(app.img_header, 'num'))) == num;
            
                % Update everything
                app.fnames = updateImgOpts(app, app.current_indices, 1);
                app.loadedImgs = loadImgs(app, app.fnames);
                updateImageSpace(app);
            
                if strcmpi(app.align_switch.Value, 'on')
                    if ~isfield(app, 'wires') || app.wire_vid ~= app.current_vid
                        app.wires = getMiniMontage(app);
                        app.wire_vid = app.current_vid;
                    end
                    updateMiniMontage(app);
                end
            
%                 guidata(hObject, app);
            end
        end

        % Menu selected function: raw_menu
        function raw_menu_Callback(app, event)
            % hObject    handle to raw_menu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            def_path = '.';
            if ~isempty(app.img_path)
                def_path = app.img_path;
            end
            tmp_raw_path = uigetdir(def_path, 'Set path to .avi''s');
            if isnumeric(tmp_raw_path)
                return;
            end
            
            % Get .avi's
            avi_dir = dir(fullfile(tmp_raw_path, '*.avi'));
            if isempty(avi_dir)
                uialert(app.figure1, 'No .avi''s found!', 'File not found')
                return;
            end
            
            % Process videos to get a list of unique video numbers
            app.avi_nums = processRawPath(avi_dir);
            
            set(app.missing_list, 'enable', 'on');
            set(app.raw_led, 'color', app.on_color);
            
            % Update missing images list if images and videos are input
            if ~isempty(app.imgs)
                updateMissingImgs(app)
            end
            
            % Update app with raw_path
            app.raw_path = tmp_raw_path;
%             guidata(hObject, app);
        end

        % Button pushed function: reject_btn
        function reject_btn_Callback(app, event)
            % hObject    handle to reject_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            decide(app, 'rejected');
        end

        % Menu selected function: shortcuts_menu
        function shortcuts_menu_Callback(app, event)
            % hObject    handle to shortcuts_menu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            msgbox(...
                {sprintf('Keep: %c', char(8594));
                sprintf('Reject: %c', char(8592));
                sprintf('Next option: %c', char(8595));
                sprintf('Previous option: %c', char(8593));
                'Next Video: N';
                'Previous Video: P'}, ...
                'Shortcuts', 'help');
        end

        % Button pushed function: start_btn
        function start_btn_Callback(app, event)
            % hObject    handle to start_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            app.started = true;
            
            % Should not be able to do batch culling
            set(app.batch_go_btn,       'enable', 'off');
            set(app.min_wd_txt,         'enable', 'off');
            set(app.min_ht_txt,         'enable', 'off');
            set(app.min_nframes_txt,    'enable', 'off');
            set(app.mod_edit_menu,      'enable', 'off');
            set(app.ht_slider,          'enable', 'off');
%             delete(app.slider_h);
            
            % Can start culling individual images
            set(app.current_img_tbl, 'enable', 'on');
            set(app.prev_btn,   'enable', 'on');
            set(app.next_btn,   'enable', 'on');
            set(app.keep_btn,   'enable', 'on');
            set(app.reject_btn, 'enable', 'on');
            set(app.finish_btn, 'enable', 'on');
            
            % Use next button callback with 0 current video
            app.current_vid = -1;
%             guidata(hObject, app);
            next_btn_Callback(app, event);
        end

        % Cell selection callback: current_img_tbl
        function current_img_tblCellSelection(app, event)
            app.current_option_idx = event.Indices(1);
            
            %% Update image space
            updateImageSpace(app);
            updateImageData(app);
            updateImgOpts(app, app.current_indices, app.current_option_idx);
            
            %% Update minimontage
            if strcmpi(get(app.align_switch, 'value'), 'on')
                if ~isfield(app, 'wires') || app.wire_vid ~= app.current_vid
                    app.wires = getMiniMontage(app);
                    app.wire_vid = app.current_vid;
                end
                updateMiniMontage(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'cull_images';
            app.figure1.WindowKeyPressFcn = createCallbackFcn(app, @figure1_WindowKeyPressFcn, true);
            app.file_menu = uimenu(app.figure1);
            app.file_menu.Text = 'File';
            app.images_menu = uimenu(app.file_menu);
            app.images_menu.MenuSelectedFcn = createCallbackFcn(app, @images_menu_Callback, true);
            app.images_menu.Accelerator = 'O';
            app.images_menu.Text = 'Load images';
            app.raw_menu = uimenu(app.file_menu);
            app.raw_menu.MenuSelectedFcn = createCallbackFcn(app, @raw_menu_Callback, true);
            app.raw_menu.Accelerator = 'R';
            app.raw_menu.Text = 'Set path to raw';
            app.dmb_menu = uimenu(app.file_menu);
            app.dmb_menu.MenuSelectedFcn = createCallbackFcn(app, @dmb_menu_Callback, true);
            app.dmb_menu.Accelerator = 'D';
            app.dmb_menu.Text = 'Set path to dmb';
            app.modalities_menu = uimenu(app.figure1);
            app.modalities_menu.Text = 'Modalities';
            app.mod_edit_menu = uimenu(app.modalities_menu);
            app.mod_edit_menu.MenuSelectedFcn = createCallbackFcn(app, @mod_edit_menu_Callback, true);
            app.mod_edit_menu.Accelerator = 'M';
            app.mod_edit_menu.Text = 'Edit';
            app.help_menu = uimenu(app.figure1);
            app.help_menu.Accelerator = 'H';
            app.help_menu.Text = 'Help';
            app.shortcuts_menu = uimenu(app.help_menu);
            app.shortcuts_menu.MenuSelectedFcn = createCallbackFcn(app, @shortcuts_menu_Callback, true);
            app.shortcuts_menu.Text = 'Shortcuts';
            app.instructions_menu = uimenu(app.help_menu);
            app.instructions_menu.Text = 'Instructions';
            app.GridLayout = uigridlayout(app.figure1);
            app.img_space = uiaxes(app.GridLayout);
            app.mini_montage = uiaxes(app.GridLayout);
            app.mini_montage.XDir = 'reverse';
            app.mini_montage.Visible = 'off';
            app.area_hist = uiaxes(app.GridLayout);
            app.paths_title = uilabel(app.GridLayout);
            app.paths_title.Text = 'Paths';
            app.mod_title = uilabel(app.GridLayout);
            app.mod_title.Text = 'Modalities';
            app.modality_listbox = uilistbox(app.GridLayout);
            app.modality_listbox.Items = {'confocal', 'split_det', 'avg'};
            app.modality_listbox.Value = 'confocal';
            app.prog_title = uilabel(app.GridLayout);
            app.prog_title.Text = 'Progress: 0%';
            app.missing_list_title = uilabel(app.GridLayout);
            app.missing_list_title.Text = 'Missing images';
            app.missing_list = uilistbox(app.GridLayout);
            app.missing_list.Items = {'Missing'};
            app.missing_list.Enable = 'off';
            app.missing_list.Value = 'Missing';
            app.batch_title = uilabel(app.GridLayout);
            app.batch_title.Text = 'Batch';
            app.mid_wid_label = uilabel(app.GridLayout);
            app.mid_wid_label.Text = 'Min width:';
            app.min_ht_label = uilabel(app.GridLayout);
            app.min_ht_label.Text = 'Min height:';
            app.min_frame_label = uilabel(app.GridLayout);
            app.min_frame_label.Text = 'Min # frames:';
            app.min_wd_txt = uieditfield(app.GridLayout, 'text');
            app.min_wd_txt.Enable = 'off';
            app.min_wd_txt.Value = '0';
            app.min_ht_txt = uieditfield(app.GridLayout, 'text');
            app.min_ht_txt.ValueChangedFcn = createCallbackFcn(app, @min_ht_txt_Callback, true);
            app.min_ht_txt.Enable = 'off';
            app.min_ht_txt.Value = '0';
            app.min_nframes_txt = uieditfield(app.GridLayout, 'text');
            app.min_nframes_txt.Enable = 'off';
            app.min_nframes_txt.Value = '0';
            app.batch_go_btn = uibutton(app.GridLayout, 'push');
            app.batch_go_btn.ButtonPushedFcn = createCallbackFcn(app, @batch_go_btn_Callback, true);
            app.batch_go_btn.Enable = 'off';
            app.batch_go_btn.Text = 'Apply';
            app.img_data_title = uilabel(app.GridLayout);
            app.img_data_title.Text = 'Image data';
            app.current_wd = uilabel(app.GridLayout);
            app.current_wd.Text = 'W:';
            app.current_ht = uilabel(app.GridLayout);
            app.current_ht.Text = 'H:';
            app.current_nframes = uilabel(app.GridLayout);
            app.current_nframes.Text = '# Frames:';
            app.current_cropped = uilabel(app.GridLayout);
            app.current_cropped.Text = 'Cropped:';
            app.keep_btn = uibutton(app.GridLayout, 'push');
            app.keep_btn.ButtonPushedFcn = createCallbackFcn(app, @keep_btn_Callback, true);
            app.keep_btn.Enable = 'off';
            app.keep_btn.Tooltip = '→';
            app.keep_btn.Text = 'Keep';
            app.reject_btn = uibutton(app.GridLayout, 'push');
            app.reject_btn.ButtonPushedFcn = createCallbackFcn(app, @reject_btn_Callback, true);
            app.reject_btn.Enable = 'off';
            app.reject_btn.Tooltip = '←';
            app.reject_btn.Text = 'Reject';
            app.finish_btn = uibutton(app.GridLayout, 'push');
            app.finish_btn.ButtonPushedFcn = createCallbackFcn(app, @finish_btn_Callback, true);
            app.finish_btn.Enable = 'off';
            app.finish_btn.Tooltip = 'Output images to output directory';
            app.finish_btn.Text = 'Finish';
            app.start_btn = uibutton(app.GridLayout, 'push');
            app.start_btn.ButtonPushedFcn = createCallbackFcn(app, @start_btn_Callback, true);
            app.start_btn.Enable = 'off';
            app.start_btn.Text = 'Start';
            app.next_btn = uibutton(app.GridLayout, 'push');
            app.next_btn.ButtonPushedFcn = createCallbackFcn(app, @next_btn_Callback, true);
            app.next_btn.Enable = 'off';
            app.next_btn.Tooltip = '(N)';
            app.next_btn.Text = 'Next';
            app.prev_btn = uibutton(app.GridLayout, 'push');
            app.prev_btn.ButtonPushedFcn = createCallbackFcn(app, @prev_btn_Callback, true);
            app.prev_btn.Enable = 'off';
            app.prev_btn.Tooltip = '(P)';
            app.prev_btn.Text = 'Previous';
            app.prog_list = uilistbox(app.GridLayout);
            app.prog_list.Items = {'Video number: 0%'};
            app.prog_list.ValueChangedFcn = createCallbackFcn(app, @prog_list_Callback, true);
            app.prog_list.Enable = 'off';
            app.prog_list.Value = 'Video number: 0%';
            app.ht_slider = uislider(app.GridLayout);
            app.ht_slider.Limits = [0 1];
            app.ht_slider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ht_slider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.ht_slider.ValueChangedFcn = createCallbackFcn(app, @ht_sliderValueChanged, true);
            app.ht_slider.ValueChangingFcn = createCallbackFcn(app, @ht_sliderValueChanging, true);
            app.ht_slider.MinorTicks = [];
            app.ht_slider.Enable = 'off';
            app.ht_slider.Tooltip = {'Set the height threshold for acceptable images'};
            app.current_img_tbl = uitable(app.GridLayout);
            app.current_img_tbl.ColumnName = {'Image Options:'};
            app.current_img_tbl.RowName = {};
            app.current_img_tbl.CellSelectionCallback = createCallbackFcn(app, @current_img_tblCellSelection, true);
            app.current_img_tbl.Enable = 'off';
            app.dmb_led = uilamp(app.GridLayout);
            app.dmb_led.Tooltip = {'Set path to the .dmb to make a copy of all the kept .dmb files'};
            app.raw_led = uilamp(app.GridLayout);
            app.raw_led.Tooltip = {'Set path to the .avi files'};
            app.MinimontageSwitchLabel = uilabel(app.GridLayout);
            app.MinimontageSwitchLabel.Text = 'Mini-montage';
            app.align_switch = uiswitch(app.GridLayout, 'slider');
            app.align_switch.Enable = 'off';
            app.raw_path_label = uilabel(app.GridLayout);
            app.raw_path_label.Text = '.avi';
            app.dmb_path_label = uilabel(app.GridLayout);
            app.dmb_path_label.Text = '.dmb';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cull_images_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @cull_images_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end